classdef VoiceLabApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NoiseMicRatioEditField       matlab.ui.control.NumericEditField
        NoiseMicRatioEditFieldLabel  matlab.ui.control.Label
        StopAllSoundsButton          matlab.ui.control.Button
        PlayFilteredAudioButton      matlab.ui.control.Button
        PlayNoisyAudioButton         matlab.ui.control.Button
        PlayNoiseButton              matlab.ui.control.Button
        PlayOriginalButton           matlab.ui.control.Button
        ButterworthIIRKaizerFIRSavitzkyGolayGaussianFilterLabel_3  matlab.ui.control.Label
        ButterworthIIRKaizerFIRSavitzkyGolayGaussianFilterLabel_2  matlab.ui.control.Label
        ButterworthIIRKaizerFIRSavitzkyGolayGaussianFilterLabel  matlab.ui.control.Label
        ApplyOtherFiltersButton      matlab.ui.control.Button
        LMSFilterButton              matlab.ui.control.Button
        AddWhiteNoiseButton          matlab.ui.control.Button
        AddCustomNoiseButton         matlab.ui.control.Button
        FileNameLabel                matlab.ui.control.Label
        FileNameText                 matlab.ui.control.Label
        AddAudioFileButton           matlab.ui.control.Button
        FilteredAudioSpectrum        matlab.ui.control.UIAxes
        FilteredAudioPlot            matlab.ui.control.UIAxes
        NoiseFreqPlot                matlab.ui.control.UIAxes
        NoiseAudioPlot               matlab.ui.control.UIAxes
        GenNoiseFreqPlot             matlab.ui.control.UIAxes
        GenNoiseAudioPlot            matlab.ui.control.UIAxes
        OrigAudioFreqPlot            matlab.ui.control.UIAxes
        OrigAudioFilePlot            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        orig_audio % Description
        noisy_audio
        noise_sample
        f_noise
        gen_noise
        filtered_audio
        orig_Fs
        firfilt
        t %Time
    end
    
    methods (Access = private)
        
        function [f, power] = fft_pow(app, audio, Fs)
            downsampling_factor = 10
            orig_audio_downsampled = downsample(audio, downsampling_factor);
            orig_Fs_downsampled = Fs / downsampling_factor;
            freq_values = fft(orig_audio_downsampled);
            audio_len = length(orig_audio_downsampled);          % number of samples
            f = (0:audio_len-1)*(orig_Fs_downsampled/audio_len);     % frequency range
            power = abs(freq_values).^2/audio_len;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddAudioFileButton
        function AddAudioFileButtonPushed(app, event)
            [audio_file,path] = uigetfile({'*.mp3'; '*.wav'})
            if audio_file
                fullpath = strcat(path, audio_file);
                [app.orig_audio, app.orig_Fs] = audioread(fullpath);
                
                app.FileNameLabel.Text = audio_file;
                %Time Domain Plot
                app.t = (1/app.orig_Fs) * (0:length(app.orig_audio)-1);
                plot(app.OrigAudioFilePlot, app.t, app.orig_audio)
                
                %Frequency Spectrum Plot
%                 downsampling_factor = 10
%                 orig_audio_downsampled = downsample(app.orig_audio, downsampling_factor);
%                 orig_Fs_downsampled = app.orig_Fs / downsampling_factor;
%                 freq_values = fft(orig_audio_downsampled);
%                 audio_len = length(orig_audio_downsampled);          % number of samples
%                 f = (0:audio_len-1)*(orig_Fs_downsampled/audio_len);     % frequency range
%                 power = abs(freq_values).^2/audio_len;
                [f, power] = app.fft_pow(app.orig_audio, app.orig_Fs);
                plot(app.OrigAudioFreqPlot, f, power)
                                            
            end
        end

        % Button down function: OrigAudioFilePlot
        function OrigAudioFilePlotButtonDown(app, event)
            
        end

        % Button pushed function: AddCustomNoiseButton
        function AddCustomNoiseButtonPushed(app, event)
            [audio_file,path] = uigetfile({'*.mp3'; '*.wav'});
            if audio_file
                fullpath = strcat(path, audio_file);
                [app.noise_sample, ~] = audioread(fullpath);
                if numel(app.noise_sample(:,1)) > numel(app.orig_audio)
                    rand_noise_index = randi(abs(numel(app.noise_sample(:,1)) - numel(app.orig_audio)) + 1, 1, 1);
                    app.gen_noise = app.noise_sample(rand_noise_index:rand_noise_index + numel(app.orig_audio) - 1);
                else
                    app.orig_audio = app.orig_audio(1:length(app.noise_sample));
                    app.gen_noise = app.noise_sample(:,1);
                    app.gen_noise = app.gen_noise';
                    app.t = app.t(1:length(app.noise_sample));
                end
                orig_power = sum(app.orig_audio.^2);
                noise_weakness_factor = 30;
                noise_power = sum(app.gen_noise.^2) * noise_weakness_factor;
                
                app.noisy_audio = app.orig_audio + sqrt(orig_power/noise_power) * app.gen_noise';
            
                
                [noise_f, noise_freq_pow] = app.fft_pow(app.gen_noise, app.orig_Fs);
                plot(app.NoiseAudioPlot, app.t, app.gen_noise)
                plot(app.NoiseFreqPlot, noise_f, noise_freq_pow)
                
                [noisy_audio_f, noisy_audio_pow] = app.fft_pow(app.noisy_audio, app.orig_Fs);
                plot(app.GenNoiseAudioPlot, app.t, app.noisy_audio)
                plot(app.GenNoiseFreqPlot, noisy_audio_f, noisy_audio_pow)
                
                
                % Correlated recording of environmental noise
%                 
            end
        end

        % Button pushed function: LMSFilterButton
        function LMSFilterButtonPushed(app, event)
            noise_ref_mic_ratio = app.NoiseMicRatioEditField.Value; % Simulate more "realistic" setup
            %Apply FIR filter to ensure correlation between noise and recording
            app.firfilt = dsp.FIRFilter;
            app.firfilt.Numerator = fir1(11,0.4);
            app.f_noise = app.firfilt(app.gen_noise * noise_ref_mic_ratio);
            
            initial_coefficents = (app.firfilt.Numerator).'-0.01;
            step = 0.001; 
            lms = dsp.LMSFilter(12,'Method','Sign-Sign LMS',...
               'StepSize',step,'InitialConditions',initial_coefficents);
            % sound(noisy_audio, o  rig_Fs)
            [~, app.filtered_audio] = lms(app.f_noise', app.noisy_audio);
            
            [filtered_audio_f, filtered_audio_pow] = app.fft_pow(app.filtered_audio, app.orig_Fs);
            plot(app.FilteredAudioPlot, app.t, app.filtered_audio)
            plot(app.FilteredAudioSpectrum, filtered_audio_f, filtered_audio_pow)
        end

        % Button pushed function: ApplyOtherFiltersButton
        function ApplyOtherFiltersButtonPushed(app, event)
            % Butterworth
            lower_freq = 200;
            upper_freq = 4500;
            % plot()
            [b,a] = butter(5, [lower_freq,upper_freq] / (app.orig_Fs/2), "bandpass");
            butter_audio = filter(b,a,app.noisy_audio);
            
            
            % Kaiser FIR
            Fs = app.orig_Fs;  % Sampling Frequency
            
            Fstop1 = 200;      % First Stopband Frequency
            Fpass1 = 300;      % First Passband Frequency
            Fpass2 = 4500;     % Second Passband Frequency
            Fstop2 = 4550;     % Second Stopband Frequency
            Dstop1 = 0.001;    % First Stopband Attenuation
            Dpass  = 0.1;      % Passband Ripple
            Dstop2 = 0.001;    % Second Stopband Attenuation
            flag   = 'scale';  % Sampling Flag
            
            % Calculate the order from the parameters using KAISERORD.
            [N,Wn,BETA,TYPE] = kaiserord([Fstop1 Fpass1 Fpass2 Fstop2]/(Fs/2), [0 ...
                                         1 0], [Dstop1 Dpass Dstop2]);
            
            % Calculate the coefficients using the FIR1 function.
            b  = fir1(N, Wn, TYPE, kaiser(N+1, BETA), flag);
            Hd = dfilt.dffir(b);
            kaiser_audio = filter(b,1,butter_audio);
            % fzv = linspace(0, 1, 1000)*(Fs/2);                                          % Frequency Vector
            % freqz(b, 1024, fzv, Fs)
            
            %Savitzky-Golay Filter
            order = 9;
            frame_len = 39;
            sgolay_audio = sgolayfilt(kaiser_audio, order, frame_len);
            
            gaussian_filter = gausswin(3);
            gaussian_audio = filter(gaussian_filter, 1, sgolay_audio);
            
            app.filtered_audio = gaussian_audio;
            
            
            [filtered_audio_f, filtered_audio_pow] = app.fft_pow(app.filtered_audio, app.orig_Fs);
            plot(app.FilteredAudioPlot, app.t, app.filtered_audio)
            plot(app.FilteredAudioSpectrum, filtered_audio_f, filtered_audio_pow)
        end

        % Button pushed function: PlayOriginalButton
        function PlayOriginalButtonPushed(app, event)
            clear sound
            sound(app.orig_audio, app.orig_Fs);
        end

        % Button pushed function: PlayNoiseButton
        function PlayNoiseButtonPushed(app, event)
            clear sound
            sound(app.noise_sample, app.orig_Fs);
        end

        % Button pushed function: PlayNoisyAudioButton
        function PlayNoisyAudioButtonPushed(app, event)
            clear sound
            sound(app.noisy_audio, app.orig_Fs);
        end

        % Button pushed function: PlayFilteredAudioButton
        function PlayFilteredAudioButtonPushed(app, event)
            clear sound
            sound(app.filtered_audio, app.orig_Fs);
        end

        % Button pushed function: StopAllSoundsButton
        function StopAllSoundsButtonPushed(app, event)
            clear sound
        end

        % Callback function
        function NoiseMicRatioEditFieldValueChanged(app, event)
            value = app.NoiseMicRatioEditField.Value;
            app.noise_ref_mic_ratio = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OrigAudioFilePlot = uiaxes(app.UIFigure);
            title(app.OrigAudioFilePlot, 'Original Audio (Time Domain)')
            xlabel(app.OrigAudioFilePlot, 'Time (s)')
            ylabel(app.OrigAudioFilePlot, 'Magnitude')
            zlabel(app.OrigAudioFilePlot, 'Z')
            app.OrigAudioFilePlot.ButtonDownFcn = createCallbackFcn(app, @OrigAudioFilePlotButtonDown, true);
            app.OrigAudioFilePlot.Tag = 'orig_audio';
            app.OrigAudioFreqPlot = uiaxes(app.UIFigure);
            title(app.OrigAudioFreqPlot, 'Original Audio Spectrum')
            xlabel(app.OrigAudioFreqPlot, 'Frequency')
            ylabel(app.OrigAudioFreqPlot, 'Power (dB)')
            zlabel(app.OrigAudioFreqPlot, 'Z')
            app.GenNoiseAudioPlot = uiaxes(app.UIFigure);
            title(app.GenNoiseAudioPlot, 'NoisyAudio (Time Domain)')
            xlabel(app.GenNoiseAudioPlot, 'Time (s)')
            ylabel(app.GenNoiseAudioPlot, 'Magnitude')
            zlabel(app.GenNoiseAudioPlot, 'Z')
            app.GenNoiseAudioPlot.Tag = 'orig_audio';
            app.GenNoiseFreqPlot = uiaxes(app.UIFigure);
            title(app.GenNoiseFreqPlot, 'Noisy Audio Spectrum')
            xlabel(app.GenNoiseFreqPlot, 'Frequency')
            ylabel(app.GenNoiseFreqPlot, 'Power (dB)')
            zlabel(app.GenNoiseFreqPlot, 'Z')
            app.NoiseAudioPlot = uiaxes(app.UIFigure);
            title(app.NoiseAudioPlot, 'Noise (Time Domain)')
            xlabel(app.NoiseAudioPlot, 'Time (s)')
            ylabel(app.NoiseAudioPlot, 'Magnitude')
            zlabel(app.NoiseAudioPlot, 'Z')
            app.NoiseAudioPlot.Tag = 'orig_audio';
            app.NoiseFreqPlot = uiaxes(app.UIFigure);
            title(app.NoiseFreqPlot, 'Noise Spectrum')
            xlabel(app.NoiseFreqPlot, 'Frequency')
            ylabel(app.NoiseFreqPlot, 'Power (dB)')
            zlabel(app.NoiseFreqPlot, 'Z')
            app.FilteredAudioPlot = uiaxes(app.UIFigure);
            title(app.FilteredAudioPlot, 'Filtered Audio (Time Domain)')
            xlabel(app.FilteredAudioPlot, 'Time (s)')
            ylabel(app.FilteredAudioPlot, 'Magnitude')
            zlabel(app.FilteredAudioPlot, 'Z')
            app.FilteredAudioPlot.Tag = 'orig_audio';
            app.FilteredAudioSpectrum = uiaxes(app.UIFigure);
            title(app.FilteredAudioSpectrum, 'Filtered Audio Spectrum')
            xlabel(app.FilteredAudioSpectrum, 'Frequency')
            ylabel(app.FilteredAudioSpectrum, 'Power (dB)')
            zlabel(app.FilteredAudioSpectrum, 'Z')
            app.AddAudioFileButton = uibutton(app.UIFigure, 'push');
            app.AddAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @AddAudioFileButtonPushed, true);
            app.AddAudioFileButton.Tag = 'add_audio';
            app.AddAudioFileButton.Text = {'Add Audio File'; ''};
            app.FileNameText = uilabel(app.UIFigure);
            app.FileNameText.Text = {'Filename:'; ''};
            app.FileNameLabel = uilabel(app.UIFigure);
            app.FileNameLabel.Text = 'None';
            app.AddCustomNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddCustomNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddCustomNoiseButtonPushed, true);
            app.AddCustomNoiseButton.Text = 'Add Custom Noise';
            app.AddWhiteNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddWhiteNoiseButton.Text = 'Add White Noise';
            app.LMSFilterButton = uibutton(app.UIFigure, 'push');
            app.LMSFilterButton.ButtonPushedFcn = createCallbackFcn(app, @LMSFilterButtonPushed, true);
            app.LMSFilterButton.Text = 'LMS Filter';
            app.ApplyOtherFiltersButton = uibutton(app.UIFigure, 'push');
            app.ApplyOtherFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyOtherFiltersButtonPushed, true);
            app.ApplyOtherFiltersButton.Text = {'Apply Other Filters'; ''};
            app.ButterworthIIRKaizerFIRSavitzkyGolayGaussianFilterLabel = uilabel(app.UIFigure);
            app.ButterworthIIRKaizerFIRSavitzkyGolayGaussianFilterLabel.Text = 'Butterworth IIR +  Kaizer FIR + Savitzky-Golay  + Gaussian Filter';
            app.ButterworthIIRKaizerFIRSavitzkyGolayGaussianFilterLabel_2 = uilabel(app.UIFigure);
            app.ButterworthIIRKaizerFIRSavitzkyGolayGaussianFilterLabel_2.Text = 'Uses simultaneous noise from a hypothetical "noise" microphone';
            app.ButterworthIIRKaizerFIRSavitzkyGolayGaussianFilterLabel_3 = uilabel(app.UIFigure);
            app.ButterworthIIRKaizerFIRSavitzkyGolayGaussianFilterLabel_3.Text = 'The ratio of the original noise the noise mic captures';
            app.PlayOriginalButton = uibutton(app.UIFigure, 'push');
            app.PlayOriginalButton.ButtonPushedFcn = createCallbackFcn(app, @PlayOriginalButtonPushed, true);
            app.PlayOriginalButton.Text = 'Play Original';
            app.PlayNoiseButton = uibutton(app.UIFigure, 'push');
            app.PlayNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNoiseButtonPushed, true);
            app.PlayNoiseButton.Text = 'Play Noise';
            app.PlayNoisyAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayNoisyAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayNoisyAudioButtonPushed, true);
            app.PlayNoisyAudioButton.Text = 'Play Noisy Audio';
            app.PlayFilteredAudioButton = uibutton(app.UIFigure, 'push');
            app.PlayFilteredAudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayFilteredAudioButtonPushed, true);
            app.PlayFilteredAudioButton.Text = 'Play Filtered Audio';
            app.StopAllSoundsButton = uibutton(app.UIFigure, 'push');
            app.StopAllSoundsButton.ButtonPushedFcn = createCallbackFcn(app, @StopAllSoundsButtonPushed, true);
            app.StopAllSoundsButton.Text = 'Stop All Sounds';
            app.NoiseMicRatioEditFieldLabel = uilabel(app.UIFigure);
            app.NoiseMicRatioEditFieldLabel.Text = 'Noise Mic Ratio';
            app.NoiseMicRatioEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VoiceLabApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end